classdef DeliaControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ControlTab                     matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        StatusLabel                    matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        COMcontroller                  matlab.ui.control.NumericEditField
        COMCEditFieldLabel             matlab.ui.control.Label
        SetPointSlider                 matlab.ui.control.Slider
        SetPointbrPressurebrbarSliderLabel  matlab.ui.control.Label
        ConnectPressure                matlab.ui.control.StateButton
        COMsensor                      matlab.ui.control.NumericEditField
        COMSEditFieldLabel             matlab.ui.control.Label
        PressureDisplay                matlab.ui.control.NumericEditField
        PressurebarEditFieldLabel      matlab.ui.control.Label
        PressureLabel                  matlab.ui.control.Label
        Pump2panel                     matlab.ui.container.Panel
        GridLayout5_2                  matlab.ui.container.GridLayout
        COMpump2                       matlab.ui.control.NumericEditField
        COMEditField_3Label            matlab.ui.control.Label
        Pump2flow                      matlab.ui.control.NumericEditField
        FlowratebrmLminsup1supEditField_2Label  matlab.ui.control.Label
        Pump2start                     matlab.ui.control.StateButton
        Pump2PLL                       matlab.ui.control.NumericEditField
        PressureLowerLimitbarEditField_2Label  matlab.ui.control.Label
        Pump2PUL                       matlab.ui.control.NumericEditField
        PressureUpperLimitbarEditField_3Label  matlab.ui.control.Label
        ConnectPump2                   matlab.ui.control.StateButton
        Pump2Label                     matlab.ui.control.Label
        Panel_6                        matlab.ui.container.Panel
        GridLayout4_2                  matlab.ui.container.GridLayout
        Temp3                          matlab.ui.control.NumericEditField
        T3CEditFieldLabel              matlab.ui.control.Label
        Temp2                          matlab.ui.control.NumericEditField
        T2CEditFieldLabel              matlab.ui.control.Label
        ConnectTemp                    matlab.ui.control.StateButton
        COMtemp                        matlab.ui.control.NumericEditField
        COMEditField_5Label            matlab.ui.control.Label
        TemperatureLabel               matlab.ui.control.Label
        V11JoeMarsdenNov2026Label_2    matlab.ui.control.Label
        Pump1panel                     matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        COMpump1                       matlab.ui.control.NumericEditField
        COMEditField_2Label            matlab.ui.control.Label
        Pump1start                     matlab.ui.control.StateButton
        Pump1flow                      matlab.ui.control.NumericEditField
        FlowratemLminLabel             matlab.ui.control.Label
        Pump1PLL                       matlab.ui.control.NumericEditField
        PressureLowerLimitbarEditFieldLabel  matlab.ui.control.Label
        Pump1PUL                       matlab.ui.control.NumericEditField
        PressureUpperLimitbarEditFieldLabel  matlab.ui.control.Label
        ConnectPump1                   matlab.ui.control.StateButton
        Pump1Label                     matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ReflectedPowerTuner            matlab.ui.control.NumericEditField
        ReflectedPowerWEditField_2Label  matlab.ui.control.Label
        ForwardPowerTuner              matlab.ui.control.NumericEditField
        ForwardPowerWEditField_2Label  matlab.ui.control.Label
        Stub3Spinner                   matlab.ui.control.Spinner
        Stub3SpinnerLabel              matlab.ui.control.Label
        Stub2Spinner                   matlab.ui.control.Spinner
        Stub2SpinnerLabel              matlab.ui.control.Label
        Stub1Spinner                   matlab.ui.control.Spinner
        Stub1SpinnerLabel              matlab.ui.control.Label
        COMtuner                       matlab.ui.control.NumericEditField
        COMEditField_7Label            matlab.ui.control.Label
        ConnectAutotuner               matlab.ui.control.StateButton
        OneStepTune                    matlab.ui.control.Button
        AllStubsHome                   matlab.ui.control.Button
        AutotunerLabel                 matlab.ui.control.Label
        StubGraph                      matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        MWLamp                         matlab.ui.control.Lamp
        COMgen                         matlab.ui.control.NumericEditField
        COMEditFieldLabel              matlab.ui.control.Label
        ReflectedPower                 matlab.ui.control.NumericEditField
        ReflectedPowerWEditFieldLabel  matlab.ui.control.Label
        ForwardPower                   matlab.ui.control.NumericEditField
        ForwardPowerWEditFieldLabel    matlab.ui.control.Label
        MWstart                        matlab.ui.control.StateButton
        PowerEditField                 matlab.ui.control.NumericEditField
        PowerWEditFieldLabel           matlab.ui.control.Label
        ConnectGenerator               matlab.ui.control.StateButton
        InstrumentDropDown             matlab.ui.control.DropDown
        InstrumentDropDownLabel        matlab.ui.control.Label
        GeneratorLabel                 matlab.ui.control.Label
        DeliaMicrowaveFlowPlatformControlStationLabel  matlab.ui.control.Label
        MonitorTab                     matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        Panel_13                       matlab.ui.container.Panel
        GridLayout9_2                  matlab.ui.container.GridLayout
        EditField                      matlab.ui.control.EditField
        StatusreadyLabel_2             matlab.ui.control.Label
        BeginMonitoringButton          matlab.ui.control.Button
        Panel_12                       matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        LogFileCheckBox                matlab.ui.control.CheckBox
        PressureCheckBox               matlab.ui.control.CheckBox
        TemperatureCheckBox            matlab.ui.control.CheckBox
        PowerCheckBox                  matlab.ui.control.CheckBox
        IncludeLabel                   matlab.ui.control.Label
        Panel_11                       matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        DataDirectoryLabel             matlab.ui.control.Label
        ChooseDirectoryButton          matlab.ui.control.Button
        StopMWbrPowerButton            matlab.ui.control.Button
        Panel_10                       matlab.ui.container.Panel
        GridLayout8_3                  matlab.ui.container.GridLayout
        UIAxes_3                       matlab.ui.control.UIAxes
        Panel_9                        matlab.ui.container.Panel
        GridLayout8_2                  matlab.ui.container.GridLayout
        UIAxes_2                       matlab.ui.control.UIAxes
        Panel_8                        matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        DeliaMicrowaveFlowPlatformControlStationLabel_2  matlab.ui.control.Label
        V11JoeMarsdenNov2026Label      matlab.ui.control.Label
        SettingsTab                    matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        PIDTab                         matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        b % bar chart for stub positions

        % Equipment connect status
        EPRconnected = 0 % 0 for disconnected, 1 for connected
        sensorconnected = 0 % 0 for disconnected, 1 for connected
        pump1connected = 0 % 0 for disconnected, 1 for connected
        pump2connected = 0 % 0 for disconnected, 1 for connected
        mwconnected = 0
        tunerconnected = 0

        % Equipment objects
        EPRobj = [] % Object placeholder for EPC
        sensorObj = [] % Placeholder for sensor
        Pump1 = [] % placeholder for pump 1
        Pump2 = [] % placeholder for pump 2
        Picolog = [] % placeholder for picolog
        Generator = [] % Placeholder for microwave generator
        Homer = [] % Placeholder for homer autotuner

        % Timers
        PressureTimer timer % Used to get pressure data
        TemperatureTimer timer % Used to get temperature data
        GeneratorTimer timer % Used to get information from generator
        AutotunerTimer timer % Used to get information from tuner

        % Structures pertaining to picolog
        % Define Buffers      
        numSamples = []
        pBufferCJ       = []
        pBufferCh1      = []
        pBufferCh2      = []
        pBufferTimes    = []
        overflow        = libpointer('int16Ptr',zeros(1, 1, 'int16'));
        min_interval_ms = []

        % Operation status
        pump1flowing = 0 
        pump2flowing = 0
        mwon = 0
        interlockstate = 1 % here, 1 is bad (open)
        outputstate = 1 % here, 1 is bad (output disabled)
    end
    
    methods (Access = private)
        
        
        function pollPressure(app)

            voltage = sensor_read_voltage(app.sensorObj);
            
            % Convert to pressure
            pressure = (voltage - 0.86)/(0.86*5 - 0.86)*40 + 1;
            
            % Update label
            app.PressureDisplay.Value = round(pressure,0,"decimals");

            % Check that it's not over 35
            if (pressure > 35) && (app.mwon == 1)
                %disp("big pressure")
                generatorStop(app.Generator)
                % Change label
                app.mwon = 0;
            end
        end
        
        function pollTemperature(app)

            % Get temp

            % Start recording
            interval = calllib('usbtc08', 'usb_tc08_run', app.Picolog, ...
                app.min_interval_ms);

            pause(1)

            % Grab measurements
            [~, ~, ~, ~] = calllib('usbtc08', ...
                'usb_tc08_get_temp', app.Picolog, app.pBufferCJ, app.pBufferTimes, app.numSamples, ...
                app.overflow, 0, 0, 0);

            [~, rpBufferCh1, ~, ~] = calllib('usbtc08', ...
                'usb_tc08_get_temp', app.Picolog, app.pBufferCh1, app.pBufferTimes, app.numSamples, ...
                app.overflow, 1, 0, 0);
            
            [~, rpBufferCh2, ~, ~] = calllib('usbtc08', ...
                'usb_tc08_get_temp', app.Picolog, app.pBufferCh2, app.pBufferTimes, app.numSamples, ...
                app.overflow, 2, 0, 0);


            % FUTURE average over a few measurements?

            % Update temp label
            app.Temp2.Value = round(double(rpBufferCh1),0,"decimals");
            app.Temp3.Value = round(double(rpBufferCh2),0,"decimals");
    
        end
        
        function pollGenerator(app)

            % Read interlock state
            app.interlockstate = generatorReadInterlock(app.Generator);

            % Read output disabled state
            app.outputstate = generatorReadOutputDisabled(app.Generator);

            % Read forward power
            forwardpower = generatorReadForwardPower(app.Generator);

            % Read reflected power
            reflectedpower = generatorReadReflectedPower(app.Generator);

            % Update fields
            
            % Update lamp colour
            if app.interlockstate + app.outputstate == 0
                app.MWLamp.Color = "green";

                % Enable start button
                if app.pump1flowing * app.pump2flowing * (1 + app.interlockstate + app.outputstate) == 1
                    app.MWstart.Enable = "on";
                end
                
            else
                app.MWLamp.Color = "red";

                % Disable start button
                app.MWstart.Enable = "off";
            end

            % Update forward power field
            app.ForwardPower.Value = forwardpower;

            % Update reflected power field
            app.ReflectedPower.Value = reflectedpower;

            % Add check that pumps are flowing
            pump1status = pumpLSflowstatus(app.Pump1);
            pump2status = pumpLSflowstatus(app.Pump2);

            if (pump1status == "0") || (pump2status == "0")
                generatorStop(app.Generator)
                app.mwon = 0;
            end

        end
        
        function pollAutotuner(app)

            % Read power data
            [forward, reflected] = autotunerReadPower(app.Homer);

            % Write to fields
            app.ForwardPowerTuner.Value = round(forward,1,"decimals");
            app.ReflectedPowerTuner.Value = round(reflected,1,"decimals");
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Definitions for temperature logging 
            app.numSamples = 1;
            app.pBufferCJ       = libpointer('singlePtr',zeros(app.numSamples, 1, 'single'));
            app.pBufferCh1      = libpointer('singlePtr',zeros(app.numSamples, 1, 'single'));
            app.pBufferCh2      = libpointer('singlePtr',zeros(app.numSamples, 1, 'single'));
            app.pBufferTimes    = libpointer('int32Ptr',zeros(app.numSamples, 1, 'int32'));
        
        % Plot autotuner graph
            app.StubGraph.Color = [0.15 0.15 0.15];
            yline(app.StubGraph,0,'-','','Color',[0.95 0.4 0.77]);
            yline(app.StubGraph,25,'-','','Color',[0.95 0.4 0.77]);

            app.PressureTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 5, ...
                'TimerFcn', @(~,~)app.pollPressure());
            
            app.TemperatureTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 5, ...
                'TimerFcn', @(~,~)app.pollTemperature());
            
            app.GeneratorTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 5, ...
                'TimerFcn', @(~,~)app.pollGenerator());            
            
            app.AutotunerTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 5, ...
                'TimerFcn', @(~,~)app.pollAutotuner());
        end

        % Value changed function: Stub1Spinner
        function Stub1SpinnerValueChanged(app, event)
            v1 = app.Stub1Spinner.Value;
            v2 = app.Stub2Spinner.Value;
            v3 = app.Stub3Spinner.Value;

            app.b = bar(app.StubGraph,[1 2 3], [v1 v2 v3],"white");
            yline(app.StubGraph,25,'-',Color=[0.95,0.4,0.77]);

            % Send to tuner
            autotunerWriteStubPositions(app.Homer, v1, v2, v3)
        end

        % Value changed function: Stub2Spinner
        function Stub2SpinnerValueChanged(app, event)
            v1 = app.Stub1Spinner.Value;
            v2 = app.Stub2Spinner.Value;
            v3 = app.Stub3Spinner.Value;

            app.b = bar(app.StubGraph,[1 2 3], [v1 v2 v3],"white");
            yline(app.StubGraph,25,'-',Color=[0.95,0.4,0.77]);

            % Send to tuner
            autotunerWriteStubPositions(app.Homer, v1, v2, v3)
        end

        % Value changed function: Stub3Spinner
        function Stub3SpinnerValueChanged(app, event)
            v1 = app.Stub1Spinner.Value;
            v2 = app.Stub2Spinner.Value;
            v3 = app.Stub3Spinner.Value;

            app.b = bar(app.StubGraph,[1 2 3], [v1 v2 v3],"white");
            yline(app.StubGraph,25,'-',Color=[0.95,0.4,0.77]);      

            % Send to tuner
            autotunerWriteStubPositions(app.Homer, v1, v2, v3)
        end

        % Value changed function: SetPointSlider
        function SetPointSliderValueChanged(app, event)
            % This slider sets the pressure. The values are approximate
            % until it is calibrated more precisely

            app.StatusLabel.Text = "Status: Changing pressure";

            value = app.SetPointSlider.Value;
            
            calfactor = 8.75;
            
            % Set the voltage on the EPC
            EPC_set_voltage(app.EPRobj,0,value/calfactor);

            app.StatusLabel.Text = "Status: Ready";
        end

        % Value changed function: ConnectPressure
        function ConnectPressureValueChanged(app, event)
            value = app.ConnectPressure.Value;
            
            if value == 1

                % This button connects to BOTH the pressure sensor and the EPR.
                app.StatusLabel.Text = "Status: Connecting to pressure equipment";

                % Connect to Sensor
    
                % Run connect driver
                comsensor = app.COMsensor.Value;
                app.sensorObj = sensor_connect(comsensor);
    
                % Connect to EPR
    
                % Run connect driver
                comcontrol = app.COMcontroller.Value;
                app.EPRobj = EPC_connect(comcontrol);
    
                % Update status
                app.sensorconnected = 1;
                app.EPRconnected = 1;
    
                % Enable voltage slider if BOTH are connected
                if app.sensorconnected * app.EPRconnected == 1
                    app.SetPointSlider.Enable = "on";
                end
    
                % Begin timer for monitoring
                start(app.PressureTimer);
    
                % Change button to disconnect
                app.ConnectPressure.Text = "Disconnect";

                app.StatusLabel.Text = "Status: Ready";

            else

                % This button disconnects from BOTH the sensor and EPR
                app.StatusLabel.Text = "Status: Disconnecting from pressure equipment";

                % Disconnect from sensor 

                % Run disconnect driver
                sensor_disconnect(app.sensorObj)
                app.sensorconnected = 0;

                % Disconnect from EPR

                 % Run disconnect driver
                 EPC_disconnect(app.EPRobj)
                 app.EPRconnected = 0;

                 % Disable slider
                app.SetPointSlider.Enable = "off";
                                
                % Change button to connect
                app.ConnectPressure.Text = "Connect";

                % FUTURE Reset label to 1 bar?

                % Disable timer
                stop(app.PressureTimer);

                app.StatusLabel.Text = "Status: Ready";

            end
        end

        % Value changed function: ConnectPump1
        function ConnectPump1ValueChanged(app, event)
            value = app.ConnectPump1.Value;
            
            if value == 1

                % Connect to pump
                app.StatusLabel.Text = "Status: Connecting to Pump 1";

                % Run connect driver
                compump1 = app.COMpump1.Value;
                app.Pump1 = pumpLSconnect(compump1);

                % Update status
                app.pump1connected = 1;

                % Enable entry fields
                app.Pump1PUL.Enable = "on";
                app.Pump1PLL.Enable = "on";
                app.Pump1flow.Enable = "on";

                % Enable start button
                app.Pump1start.Enable = "on";

                % Change button to disconnect
                app.ConnectPump1.Text = "Disconnect";

                app.StatusLabel.Text = "Status: Ready";

            else

                % Disconnect from pump
                app.StatusLabel.Text = "Status: Disconnecting from Pump 1";

                % Run disconnect driver
                pumpLSdisconnect(app.Pump1);

                % Update status
                app.pump1connected = 0;

                % Disable entry fields
                app.Pump1PUL.Enable = "off";
                app.Pump1PLL.Enable = "off";
                app.Pump1flow.Enable = "off";

                % Disable start button
                app.Pump1start.Enable = "off";

                % Change button to connect
                app.ConnectPump1.Text = "Connect";

                app.StatusLabel.Text = "Status: Ready";

            end
        end

        % Value changed function: ConnectPump2
        function ConnectPump2ValueChanged(app, event)
            value = app.ConnectPump2.Value;
            
            if value == 1

                % Connect to pump
                app.StatusLabel.Text = "Connecting to Pump 2";

                % Run connect driver
                compump2 = app.COMpump2.Value;
                app.Pump2 = pumpLSconnect(compump2);

                % Update status
                app.pump2connected = 1;

                % Enable entry fields
                app.Pump2PUL.Enable = "on";
                app.Pump2PLL.Enable = "on";
                app.Pump2flow.Enable = "on";

                % Enable start button
                app.Pump2start.Enable = "on";

                % Change button to disconnect
                app.ConnectPump2.Text = "Disconnect";

                app.StatusLabel.Text = "Status: Ready";

            else

                % Disconnect from pump
                app.StatusLabel.Text = "Status: Disconnecting from Pump 2";

                % Run disconnect driver
                pumpLSdisconnect(app.Pump2);

                % Update status
                app.pump2connected = 0;

                % Disable entry fields
                app.Pump2PUL.Enable = "off";
                app.Pump2PLL.Enable = "off";
                app.Pump2flow.Enable = "off";

                % Disable start button
                app.Pump2start.Enable = "off";

                % Change button to connect
                app.ConnectPump2.Text = "Connect";

                app.StatusLabel.Text = "Status: Ready";
                
            end
        end

        % Value changed function: ConnectTemp
        function ConnectTempValueChanged(app, event)
            value = app.ConnectTemp.Value;
          
            if value == 1
                % Connect to picolog
                app.StatusLabel.Text = "Status: Connecting to Picolog";

                % Load Configuration Information
                USBTC08Config;

                % Load library
                loadlibrary('usbtc08.dll', @usbtc08MFile);
    
                % Check if the library is loaded
                if ~libisloaded('usbtc08')
                
                    error('Library usbtc08.dll or usbtc08MFile not found'); 
                
                end
              
                % Open Unit and Display Information
                app.Picolog = calllib('usbtc08', 'usb_tc08_open_unit');
                
                if app.Picolog > 0
                    
                    disp('Connected to USB TC-08 device...');
                    fprintf('\n');
                    
                elseif app.Picolog == 0
                    
                    warning('USBTC08Example:NoMoreUnitsFound', 'No more USB TC-08 units found');
                    
                else
                   
                    unloadlibrary('usbtc08');
                    error('USBTC08Example:UnitFailedToOpen', 'Unit failed to open.');
                    return;
                    
                end
                
                % Get unit information
                
                infoString = blanks(512);
                
                [status.unitinfo, infostring] = calllib('usbtc08', 'usb_tc08_get_formatted_info', ...
                    app.Picolog, infoString, length(infoString));
                
                disp(infostring);
                
                error = calllib('usbtc08', 'usb_tc08_get_last_error', app.Picolog);
                
                % Configure Device
                
                % Set up channels
                
                usbtc08MaxChannels = usbtc08Enuminfo.enUSBTC08Channels.USBTC08_MAX_CHANNELS;
                typek = 'K';
                
                % Enable Cold Junction and Channel 1
                for n = 1:3
                
                    status.setChannel = calllib('usbtc08', 'usb_tc08_set_channel', app.Picolog, ...
                        (n - 1), int8(typek));
                    
                end
                
                % Set mains filter to 50Hz
                
                status.mainsFilter = calllib('usbtc08', 'usb_tc08_set_mains', app.Picolog, 0);
                
                % Find minimum sampling interval
                app.min_interval_ms = calllib('usbtc08', 'usb_tc08_get_minimum_interval_ms', app.Picolog);

                % Start timer
                start(app.TemperatureTimer);


                % Change button to disconnect
                app.ConnectTemp.Text = "Disconnect";

                app.StatusLabel.Text = "Status: Ready";

            else
                % disconnect from picolog
                app.StatusLabel.Text = "Status: Disconnecting from Picolog";
                
                % Stop recording
                stopit = calllib('usbtc08', 'usb_tc08_stop', app.Picolog);

                % Close Connection to Unit and Unload Library
                
                exit = calllib('usbtc08', 'usb_tc08_close_unit', app.Picolog);
                
                unloadlibrary('usbtc08');

                % Stop timer
                stop(app.TemperatureTimer);

                % Change button to connect
                app.ConnectTemp.Text = "Connect";

                app.StatusLabel.Text = "Status: Ready";

            end
        end

        % Value changed function: Pump1start
        function Pump1startValueChanged(app, event)
            value = app.Pump1start.Value;
            
            if value == 1
                % Start the pump
                app.StatusLabel.Text = "Status: Starting Pump 1";

                % Run start driver
                pumpLSstart(app.Pump1)
                
                % Update status
                app.pump1flowing = 1;

                % If BOTH pumps are flowing, allow microwaving
                if app.pump1flowing * app.pump2flowing * app.mwconnected == 1
                    app.MWstart.Enable = "on";
                end

                % Change button to stop
                app.Pump1start.Text = "Stop";

                app.StatusLabel.Text = "Status: Ready";

            else
                % Stop the pump
                app.StatusLabel.Text = "Status: Stopping Pump 1";

                % Run stop driver
                pumpLSstop(app.Pump1)

                % Update status
                app.pump1flowing = 0;

                % If microwaving, stop
                % NOTE this is just a failsafe, the button shouldn't be
                % available if there is microwave output
                if app.mwon == 1
                    % FUTURE stop

                    app.mwon = 0;
                end

                % Update microwave button 
                app.MWstart.Enable = "off";

                % Change button to start
                app.Pump1start.Text = "Start";

                app.StatusLabel.Text = "Status: Ready";
            end

        end

        % Value changed function: Pump2start
        function Pump2startValueChanged(app, event)
            value = app.Pump2start.Value;
            
            if value == 1
                % Start the pump
                app.StatusLabel.Text = "Status: Starting Pump 2";

                % Run start driver
                pumpLSstart(app.Pump2)
                
                % Update status
                app.pump2flowing = 1;

                % If BOTH pumps are flowing, allow microwaving
                if app.pump1flowing * app.pump2flowing * app.mwconnected == 1
                    app.MWstart.Enable = "on";
                end

                % Change button to stop
                app.Pump2start.Text = "Stop";

                app.StatusLabel.Text = "Status: Ready";

            else
                % Stop the pump
                app.StatusLabel.Text = "Status: Stopping Pump 2";

                % Run stop driver
                pumpLSstop(app.Pump2)

                % Update status
                app.pump2flowing = 0;

                % If microwaving, stop
                % NOTE this is just a failsafe, the button shouldn't be
                % available if there is microwave output
                if app.mwon == 1
                    % FUTURE stop

                    app.mwon = 0;
                end

                % Update microwave button 
                app.MWstart.Enable = "off";

                % Change button to start
                app.Pump2start.Text = "Start";

                app.StatusLabel.Text = "Status: Ready";
            end

        end

        % Value changed function: Pump1PUL
        function Pump1PULValueChanged(app, event)
            value = app.Pump1PUL.Value;
            
            app.StatusLabel.Text = "Status: Updating Pump 1 Upper Pressure Limit";

            % Change upper pressure limit in bar
            pumpLSwritepressUL(app.Pump1, value)

            app.StatusLabel.Text = "Status: Ready";
        end

        % Value changed function: Pump1PLL
        function Pump1PLLValueChanged(app, event)
            value = app.Pump1PLL.Value;
            
            app.StatusLabel.Text = "Status: Updating Pump 1 Lower Pressure Limit";

            % Change upper pressure limit in bar
            pumpLSwritepressLL(app.Pump1, value)

            app.StatusLabel.Text = "Status: Ready";
        end

        % Value changed function: Pump2PUL
        function Pump2PULValueChanged(app, event)
            value = app.Pump2PUL.Value;
            
            app.StatusLabel.Text = "Status: Updating Pump 2 Upper Pressure Limit";

            % Change upper pressure limit in bar
            pumpLSwritepressUL(app.Pump2, value)

            app.StatusLabel.Text = "Status: Ready";
        end

        % Value changed function: Pump2PLL
        function Pump2PLLValueChanged(app, event)
            value = app.Pump2PLL.Value;
           
            app.StatusLabel.Text = "Status: Updating Pump 2 Lower Pressure Limit";

            % Change upper pressure limit in bar
            pumpLSwritepressLL(app.Pump2, value)

            app.StatusLabel.Text = "Status: Ready";            
        end

        % Value changed function: Pump1flow
        function Pump1flowValueChanged(app, event)
            value = app.Pump1flow.Value;
            
            app.StatusLabel.Text = "Status: Updating Pump 1 Flowrate";

            % Change flowrate driver
            pumpLSwriteflow(app.Pump1,value)

            app.StatusLabel.Text = "Status: Ready";            
        end

        % Value changed function: Pump2flow
        function Pump2flowValueChanged(app, event)
            value = app.Pump2flow.Value;
            
            app.StatusLabel.Text = "Status: Updating Pump 2 Flowrate";

            % Change flowrate driver
            pumpLSwriteflow(app.Pump2,value)

            app.StatusLabel.Text = "Status: Ready"; 
        end

        % Value changed function: ConnectGenerator
        function ConnectGeneratorValueChanged(app, event)
            value = app.ConnectGenerator.Value;

            if value == 1
                % connect to generator
                % FUTURE specify which generator

                app.StatusLabel.Text = "Status: Connecting to microwave generator";

                % Run driver
                app.Generator = generatorConnect(app.COMgen.Value);

                % Update status
                app.mwconnected = 1;

                % Enable start button IF pumps are flowing also
                if app.pump1flowing * app.pump2flowing == 1
                    app.MWstart.Enable = "on";
                end

                % Enable change power
                app.PowerEditField.Enable = "on";

                % Start timer
                start(app.GeneratorTimer);

                % Change button to disconnect
                app.ConnectGenerator.Text = "Disconnect";

                app.StatusLabel.Text = "Status: Ready"; 

            else
                % disconnect from generator

                app.StatusLabel.Text = "Status: Disconnecting from microwave generator";

                % If generator is running, stop
                if app.mwon == 1
                    generatorStop(app.Generator)
                end

                % Run disonnect driver
                generatorDisconnect(app.Generator)

                % Update status
                app.mwconnected = 0;

                % Disable start button
                app.MWstart.Enable = "off";

                % Disable change power
                app.PowerEditField.Enable = "off";

                % Stop timer
                stop(app.GeneratorTimer);

                % Reset button to base colour
                app.MWLamp.Color = [0.95,0.40,0.77];

                % Change button to connect
                app.ConnectGenerator.Text = "Connect";

                app.StatusLabel.Text = "Status: Ready"; 

            end
            
        end

        % Value changed function: MWstart
        function MWstartValueChanged(app, event)
            value = app.MWstart.Value;
            
            if value == 1
                % Start the microwave generation
                
                app.StatusLabel.Text = "Status: Starting microwave generator";

                % Check if interlock is open or closed, and if output
                % disabled
                app.interlockstate = generatorReadInterlock(app.Generator);
                app.outputstate = generatorReadOutputDisabled(app.Generator);

                % Run driver IF pumps are flowing AND interlock is closed
                % Shouldn't be possible otherwise anyway
                if app.pump1flowing * app.pump2flowing * (1 + app.interlockstate + app.outputstate) == 1
                    generatorStart(app.Generator)

                    % Update status
                    app.mwon = 1;

                    % Disable pressure change 
                    app.SetPointSlider.Enable = "off";
                    app.ConnectPressure.Enable = "off";

                    % Disable pump stop or change
                    app.Pump1flow.Enable = "off";
                    app.Pump2flow.Enable = "off";
                    app.Pump1start.Enable = "off";
                    app.Pump2start.Enable = "off";
                    app.ConnectPump1.Enable = "off";
                    app.ConnectPump2.Enable = "off";

                    % Disable generator disconnect
                    app.ConnectGenerator.Enable = "off";


                end

                % Change label to stop
                app.MWstart.Text = "Stop";

                app.StatusLabel.Text = "Status: Ready"; 

            else
                % Stop the microwave generation
                app.StatusLabel.Text = "Status: Stopping microwave generator";
                
                % Run this if interlock is CLOSED. Open will error. Also
                % output state
                app.interlockstate = generatorReadInterlock(app.Generator);
                app.outputstate = generatorReadOutputDisabled(app.Generator);

                if app.interlockstate + app.outputstate == 0
                    % Run driver
                    generatorStop(app.Generator)
                end

                % Update status
                app.mwon = 0;
    
                % Enable pressure change 
                app.SetPointSlider.Enable = "on";
                app.ConnectPressure.Enable = "on";
    
                % Enable pump stop or change
                app.Pump1flow.Enable = "on";
                app.Pump2flow.Enable = "on";
                app.Pump1start.Enable = "on";
                app.Pump2start.Enable = "on";
                app.ConnectPump1.Enable = "on";
                app.ConnectPump2.Enable = "on";
    
                % Disable generator disconnect
                app.ConnectGenerator.Enable = "on";
                
                % Change label to start
                app.MWstart.Text = "Start";
    
                app.StatusLabel.Text = "Status: Ready"; 

            end
        end

        % Value changed function: PowerEditField
        function PowerEditFieldValueChanged(app, event)
            value = app.PowerEditField.Value;
            
            app.StatusLabel.Text = "Status: Changing generator power";

            % Run driver
            generatorWritePower(app.Generator, value)

            app.StatusLabel.Text = "Status: Ready"; 
            
        end

        % Value changed function: ConnectAutotuner
        function ConnectAutotunerValueChanged(app, event)
            value = app.ConnectAutotuner.Value;
            
            if value == 1
                % connect to autotuner
                app.StatusLabel.Text = "Status: Connecting to autotuner";

                % Run driver
                app.Homer = autotunerConnect(app.COMtuner.Value);

                % Update status
                app.tunerconnected = 1;

                % Enable buttons
                app.AllStubsHome.Enable = "on";
                app.OneStepTune.Enable = "on";
                app.Stub1Spinner.Enable = "on";
                app.Stub2Spinner.Enable = "on";
                app.Stub3Spinner.Enable = "on";

                % Start timer
                start(app.AutotunerTimer);

                % Change text to disconnect
                app.ConnectAutotuner.Text = "Disconnect";

                app.StatusLabel.Text = "Status: Ready"; 
            else
                % disconnect from autotuner
                app.StatusLabel.Text = "Status: Disconnecting from autotuner";

                % Run driver
                autotunerDisconnect(app.Homer)

                % Update status
                app.tunerconnected = 0;

                % Disable buttons
                app.AllStubsHome.Enable = "off";
                app.OneStepTune.Enable = "off";
                app.Stub1Spinner.Enable = "off";
                app.Stub2Spinner.Enable = "off";
                app.Stub3Spinner.Enable = "off";

                % Stop timer
                stop(app.AutotunerTimer);

                % Change text to connect
                app.ConnectAutotuner.Text = "Connect";

                app.StatusLabel.Text = "Status: Ready"; 

            end
        end

        % Button pushed function: AllStubsHome
        function AllStubsHomeButtonPushed(app, event)
            % Run driver
            autotunerStubsHome(app.Homer);

            % Reset graph and sliders
            app.Stub1Spinner.Value = 0;
            app.Stub2Spinner.Value = 0;
            app.Stub3Spinner.Value = 0;
            
            v1 = app.Stub1Spinner.Value;
            v2 = app.Stub2Spinner.Value;
            v3 = app.Stub3Spinner.Value;

            app.b = bar(app.StubGraph,[1 2 3], [v1 v2 v3],"white");
            yline(app.StubGraph,25,'-',Color=[0.95,0.4,0.77]);
        end

        % Button pushed function: OneStepTune
        function OneStepTuneButtonPushed(app, event)
            app.StatusLabel.Text = "Status: One-step tune running";
            app.OneStepTune.Enable = "off";

            % Run driver
            autotunerOneStepTune(app.Homer)
            pause(0.3)
            % Read new stub positions
            [s1,s2,s3] = autotunerReadStubPositions(app.Homer)

            % Update sliders
            app.Stub1Spinner.Value = s1;
            app.Stub2Spinner.Value = s2;
            app.Stub3Spinner.Value = s3;

            % Update graph
            app.b = bar(app.StubGraph,[1 2 3], [s1 s2 s3],"white");
            yline(app.StubGraph,25,'-',Color=[0.95,0.4,0.77]);

            app.OneStepTune.Enable = "on";
            app.StatusLabel.Text = "Status: Ready";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.GridLayout = uigridlayout(app.ControlTab);
            app.DeliaMicrowaveFlowPlatformControlStationLabel = uilabel(app.GridLayout);
            app.DeliaMicrowaveFlowPlatformControlStationLabel.Text = 'Delia Microwave Flow Platform Control Station';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GeneratorLabel = uilabel(app.GridLayout2);
            app.GeneratorLabel.Text = 'Generator';
            app.InstrumentDropDownLabel = uilabel(app.GridLayout2);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.GridLayout2);
            app.InstrumentDropDown.Items = {'2 kW', '1 kW'};
            app.InstrumentDropDown.Value = '2 kW';
            app.ConnectGenerator = uibutton(app.GridLayout2, 'state');
            app.ConnectGenerator.ValueChangedFcn = createCallbackFcn(app, @ConnectGeneratorValueChanged, true);
            app.ConnectGenerator.Text = 'Connect';
            app.PowerWEditFieldLabel = uilabel(app.GridLayout2);
            app.PowerWEditFieldLabel.Text = 'Power / W';
            app.PowerEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PowerEditField.ValueChangedFcn = createCallbackFcn(app, @PowerEditFieldValueChanged, true);
            app.PowerEditField.Enable = 'off';
            app.MWstart = uibutton(app.GridLayout2, 'state');
            app.MWstart.ValueChangedFcn = createCallbackFcn(app, @MWstartValueChanged, true);
            app.MWstart.Enable = 'off';
            app.MWstart.Text = 'Start';
            app.ForwardPowerWEditFieldLabel = uilabel(app.GridLayout2);
            app.ForwardPowerWEditFieldLabel.Text = 'Forward Power / W';
            app.ForwardPower = uieditfield(app.GridLayout2, 'numeric');
            app.ReflectedPowerWEditFieldLabel = uilabel(app.GridLayout2);
            app.ReflectedPowerWEditFieldLabel.Text = 'Reflected Power / W';
            app.ReflectedPower = uieditfield(app.GridLayout2, 'numeric');
            app.COMEditFieldLabel = uilabel(app.GridLayout2);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMgen = uieditfield(app.GridLayout2, 'numeric');
            app.COMgen.Value = 16;
            app.MWLamp = uilamp(app.GridLayout2);
            app.Panel_5 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_5);
            app.StubGraph = uiaxes(app.GridLayout3);
            app.StubGraph.XLim = [0.5 3.5];
            app.StubGraph.YLim = [0 25];
            app.StubGraph.XLimitMethod = 'tight';
            app.StubGraph.YLimitMethod = 'tight';
            app.StubGraph.ZLimitMethod = 'tight';
            app.StubGraph.XTick = [1 2 3];
            app.StubGraph.YTick = [];
            app.StubGraph.ZTick = [];
            app.AutotunerLabel = uilabel(app.GridLayout3);
            app.AutotunerLabel.Text = 'Autotuner';
            app.AllStubsHome = uibutton(app.GridLayout3, 'push');
            app.AllStubsHome.ButtonPushedFcn = createCallbackFcn(app, @AllStubsHomeButtonPushed, true);
            app.AllStubsHome.Enable = 'off';
            app.AllStubsHome.Text = 'All Stubs Home Position';
            app.OneStepTune = uibutton(app.GridLayout3, 'push');
            app.OneStepTune.ButtonPushedFcn = createCallbackFcn(app, @OneStepTuneButtonPushed, true);
            app.OneStepTune.Enable = 'off';
            app.OneStepTune.Text = 'One-Step Tune';
            app.ConnectAutotuner = uibutton(app.GridLayout3, 'state');
            app.ConnectAutotuner.ValueChangedFcn = createCallbackFcn(app, @ConnectAutotunerValueChanged, true);
            app.ConnectAutotuner.Text = 'Connect';
            app.COMEditField_7Label = uilabel(app.GridLayout3);
            app.COMEditField_7Label.Text = 'COM';
            app.COMtuner = uieditfield(app.GridLayout3, 'numeric');
            app.COMtuner.Value = 11;
            app.Stub1SpinnerLabel = uilabel(app.GridLayout3);
            app.Stub1SpinnerLabel.Text = 'Stub 1';
            app.Stub1Spinner = uispinner(app.GridLayout3);
            app.Stub1Spinner.Step = 1.51333333333333;
            app.Stub1Spinner.Limits = [0 22.71];
            app.Stub1Spinner.ValueDisplayFormat = '%.2f';
            app.Stub1Spinner.ValueChangedFcn = createCallbackFcn(app, @Stub1SpinnerValueChanged, true);
            app.Stub1Spinner.Enable = 'off';
            app.Stub2SpinnerLabel = uilabel(app.GridLayout3);
            app.Stub2SpinnerLabel.Text = 'Stub 2';
            app.Stub2Spinner = uispinner(app.GridLayout3);
            app.Stub2Spinner.Step = 1.51333333333333;
            app.Stub2Spinner.Limits = [0 22.71];
            app.Stub2Spinner.ValueDisplayFormat = '%.2f';
            app.Stub2Spinner.ValueChangedFcn = createCallbackFcn(app, @Stub2SpinnerValueChanged, true);
            app.Stub2Spinner.Enable = 'off';
            app.Stub3SpinnerLabel = uilabel(app.GridLayout3);
            app.Stub3SpinnerLabel.Text = 'Stub 3';
            app.Stub3Spinner = uispinner(app.GridLayout3);
            app.Stub3Spinner.Step = 1.51333333333333;
            app.Stub3Spinner.Limits = [0 22.71];
            app.Stub3Spinner.ValueDisplayFormat = '%.2f';
            app.Stub3Spinner.ValueChangedFcn = createCallbackFcn(app, @Stub3SpinnerValueChanged, true);
            app.Stub3Spinner.Enable = 'off';
            app.ForwardPowerWEditField_2Label = uilabel(app.GridLayout3);
            app.ForwardPowerWEditField_2Label.Text = 'Forward Power / W';
            app.ForwardPowerTuner = uieditfield(app.GridLayout3, 'numeric');
            app.ReflectedPowerWEditField_2Label = uilabel(app.GridLayout3);
            app.ReflectedPowerWEditField_2Label.Text = 'Reflected Power / W';
            app.ReflectedPowerTuner = uieditfield(app.GridLayout3, 'numeric');
            app.Pump1panel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Pump1panel);
            app.Pump1Label = uilabel(app.GridLayout5);
            app.Pump1Label.Text = 'Pump 1';
            app.ConnectPump1 = uibutton(app.GridLayout5, 'state');
            app.ConnectPump1.ValueChangedFcn = createCallbackFcn(app, @ConnectPump1ValueChanged, true);
            app.ConnectPump1.Text = 'Connect';
            app.PressureUpperLimitbarEditFieldLabel = uilabel(app.GridLayout5);
            app.PressureUpperLimitbarEditFieldLabel.Text = 'Pressure Upper Limit / bar';
            app.Pump1PUL = uieditfield(app.GridLayout5, 'numeric');
            app.Pump1PUL.ValueChangedFcn = createCallbackFcn(app, @Pump1PULValueChanged, true);
            app.Pump1PUL.Enable = 'off';
            app.Pump1PUL.Value = 40;
            app.PressureLowerLimitbarEditFieldLabel = uilabel(app.GridLayout5);
            app.PressureLowerLimitbarEditFieldLabel.Text = 'Pressure Lower Limit / bar';
            app.Pump1PLL = uieditfield(app.GridLayout5, 'numeric');
            app.Pump1PLL.ValueChangedFcn = createCallbackFcn(app, @Pump1PLLValueChanged, true);
            app.Pump1PLL.Enable = 'off';
            app.Pump1PLL.Value = 1;
            app.FlowratemLminLabel = uilabel(app.GridLayout5);
            app.FlowratemLminLabel.Interpreter = 'html';
            app.FlowratemLminLabel.Text = 'Flowrate</br>/ mL min<sup>-1</sup>';
            app.Pump1flow = uieditfield(app.GridLayout5, 'numeric');
            app.Pump1flow.ValueChangedFcn = createCallbackFcn(app, @Pump1flowValueChanged, true);
            app.Pump1flow.Enable = 'off';
            app.Pump1flow.Value = 1;
            app.Pump1start = uibutton(app.GridLayout5, 'state');
            app.Pump1start.ValueChangedFcn = createCallbackFcn(app, @Pump1startValueChanged, true);
            app.Pump1start.Enable = 'off';
            app.Pump1start.Text = 'Start';
            app.COMEditField_2Label = uilabel(app.GridLayout5);
            app.COMEditField_2Label.Text = 'COM';
            app.COMpump1 = uieditfield(app.GridLayout5, 'numeric');
            app.COMpump1.Value = 13;
            app.V11JoeMarsdenNov2026Label_2 = uilabel(app.GridLayout);
            app.V11JoeMarsdenNov2026Label_2.Text = 'V1.1 Joe Marsden Nov 2026';
            app.Panel_6 = uipanel(app.GridLayout);
            app.GridLayout4_2 = uigridlayout(app.Panel_6);
            app.TemperatureLabel = uilabel(app.GridLayout4_2);
            app.TemperatureLabel.Text = 'Temperature';
            app.COMEditField_5Label = uilabel(app.GridLayout4_2);
            app.COMEditField_5Label.Text = 'COM';
            app.COMtemp = uieditfield(app.GridLayout4_2, 'numeric');
            app.COMtemp.Enable = 'off';
            app.ConnectTemp = uibutton(app.GridLayout4_2, 'state');
            app.ConnectTemp.ValueChangedFcn = createCallbackFcn(app, @ConnectTempValueChanged, true);
            app.ConnectTemp.Text = 'Connect';
            app.T2CEditFieldLabel = uilabel(app.GridLayout4_2);
            app.T2CEditFieldLabel.Text = 'T2 / °C';
            app.Temp2 = uieditfield(app.GridLayout4_2, 'numeric');
            app.T3CEditFieldLabel = uilabel(app.GridLayout4_2);
            app.T3CEditFieldLabel.Text = 'T3 / °C';
            app.Temp3 = uieditfield(app.GridLayout4_2, 'numeric');
            app.Pump2panel = uipanel(app.GridLayout);
            app.GridLayout5_2 = uigridlayout(app.Pump2panel);
            app.Pump2Label = uilabel(app.GridLayout5_2);
            app.Pump2Label.Text = 'Pump 2';
            app.ConnectPump2 = uibutton(app.GridLayout5_2, 'state');
            app.ConnectPump2.ValueChangedFcn = createCallbackFcn(app, @ConnectPump2ValueChanged, true);
            app.ConnectPump2.Text = 'Connect';
            app.PressureUpperLimitbarEditField_3Label = uilabel(app.GridLayout5_2);
            app.PressureUpperLimitbarEditField_3Label.Text = 'Pressure Upper Limit / bar';
            app.Pump2PUL = uieditfield(app.GridLayout5_2, 'numeric');
            app.Pump2PUL.ValueChangedFcn = createCallbackFcn(app, @Pump2PULValueChanged, true);
            app.Pump2PUL.Enable = 'off';
            app.Pump2PUL.Value = 40;
            app.PressureLowerLimitbarEditField_2Label = uilabel(app.GridLayout5_2);
            app.PressureLowerLimitbarEditField_2Label.Text = 'Pressure Lower Limit / bar';
            app.Pump2PLL = uieditfield(app.GridLayout5_2, 'numeric');
            app.Pump2PLL.ValueChangedFcn = createCallbackFcn(app, @Pump2PLLValueChanged, true);
            app.Pump2PLL.Enable = 'off';
            app.Pump2PLL.Value = 1;
            app.Pump2start = uibutton(app.GridLayout5_2, 'state');
            app.Pump2start.ValueChangedFcn = createCallbackFcn(app, @Pump2startValueChanged, true);
            app.Pump2start.Enable = 'off';
            app.Pump2start.Text = 'Start';
            app.FlowratebrmLminsup1supEditField_2Label = uilabel(app.GridLayout5_2);
            app.FlowratebrmLminsup1supEditField_2Label.Interpreter = 'html';
            app.FlowratebrmLminsup1supEditField_2Label.Text = 'Flowrate</br>/ mL min<sup>-1</sup>';
            app.Pump2flow = uieditfield(app.GridLayout5_2, 'numeric');
            app.Pump2flow.ValueChangedFcn = createCallbackFcn(app, @Pump2flowValueChanged, true);
            app.Pump2flow.Enable = 'off';
            app.Pump2flow.Value = 1;
            app.COMEditField_3Label = uilabel(app.GridLayout5_2);
            app.COMEditField_3Label.Text = 'COM';
            app.COMpump2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.COMpump2.Value = 6;
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel_4);
            app.PressureLabel = uilabel(app.GridLayout4);
            app.PressureLabel.Text = 'Pressure';
            app.PressurebarEditFieldLabel = uilabel(app.GridLayout4);
            app.PressurebarEditFieldLabel.Text = 'Pressure / bar';
            app.PressureDisplay = uieditfield(app.GridLayout4, 'numeric');
            app.COMSEditFieldLabel = uilabel(app.GridLayout4);
            app.COMSEditFieldLabel.Text = 'COM (S)';
            app.COMsensor = uieditfield(app.GridLayout4, 'numeric');
            app.COMsensor.Value = 18;
            app.ConnectPressure = uibutton(app.GridLayout4, 'state');
            app.ConnectPressure.ValueChangedFcn = createCallbackFcn(app, @ConnectPressureValueChanged, true);
            app.ConnectPressure.Text = 'Connect';
            app.SetPointbrPressurebrbarSliderLabel = uilabel(app.GridLayout4);
            app.SetPointbrPressurebrbarSliderLabel.Interpreter = 'html';
            app.SetPointbrPressurebrbarSliderLabel.Text = 'Set Point<br/>Pressure<br/>/ bar';
            app.SetPointSlider = uislider(app.GridLayout4);
            app.SetPointSlider.Limits = [0 30];
            app.SetPointSlider.MajorTicks = [0 5 10 15 20 25 30];
            app.SetPointSlider.ValueChangedFcn = createCallbackFcn(app, @SetPointSliderValueChanged, true);
            app.SetPointSlider.Step = 1;
            app.SetPointSlider.Enable = 'off';
            app.COMCEditFieldLabel = uilabel(app.GridLayout4);
            app.COMCEditFieldLabel.Text = 'COM (C)';
            app.COMcontroller = uieditfield(app.GridLayout4, 'numeric');
            app.COMcontroller.Value = 19;
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status: ready';
            app.MonitorTab = uitab(app.TabGroup);
            app.MonitorTab.Title = 'Monitor';
            app.GridLayout7 = uigridlayout(app.MonitorTab);
            app.V11JoeMarsdenNov2026Label = uilabel(app.GridLayout7);
            app.V11JoeMarsdenNov2026Label.Text = 'V1.1 Joe Marsden Nov 2026';
            app.DeliaMicrowaveFlowPlatformControlStationLabel_2 = uilabel(app.GridLayout7);
            app.DeliaMicrowaveFlowPlatformControlStationLabel_2.Text = 'Delia Microwave Flow Platform Control Station';
            app.Panel_8 = uipanel(app.GridLayout7);
            app.GridLayout8 = uigridlayout(app.Panel_8);
            app.UIAxes = uiaxes(app.GridLayout8);
            xlabel(app.UIAxes, 'Time / min')
            ylabel(app.UIAxes, 'Power / W')
            zlabel(app.UIAxes, 'Z')
            app.Panel_9 = uipanel(app.GridLayout7);
            app.GridLayout8_2 = uigridlayout(app.Panel_9);
            app.UIAxes_2 = uiaxes(app.GridLayout8_2);
            xlabel(app.UIAxes_2, 'Time / min')
            ylabel(app.UIAxes_2, 'Temperature / °C')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_10 = uipanel(app.GridLayout7);
            app.GridLayout8_3 = uigridlayout(app.Panel_10);
            app.UIAxes_3 = uiaxes(app.GridLayout8_3);
            xlabel(app.UIAxes_3, 'Time / min')
            ylabel(app.UIAxes_3, 'Pressure / bar')
            zlabel(app.UIAxes_3, 'Z')
            app.StopMWbrPowerButton = uibutton(app.GridLayout7, 'push');
            app.StopMWbrPowerButton.Enable = 'off';
            app.StopMWbrPowerButton.Interpreter = 'html';
            app.StopMWbrPowerButton.Text = 'Stop MW<br/>Power';
            app.ChooseDirectoryButton = uibutton(app.GridLayout7, 'push');
            app.ChooseDirectoryButton.Text = 'Choose Directory';
            app.Panel_11 = uipanel(app.GridLayout7);
            app.GridLayout9 = uigridlayout(app.Panel_11);
            app.DataDirectoryLabel = uilabel(app.GridLayout9);
            app.DataDirectoryLabel.Text = 'Data Directory';
            app.Panel_12 = uipanel(app.GridLayout7);
            app.GridLayout10 = uigridlayout(app.Panel_12);
            app.IncludeLabel = uilabel(app.GridLayout10);
            app.IncludeLabel.Text = 'Include';
            app.PowerCheckBox = uicheckbox(app.GridLayout10);
            app.PowerCheckBox.Text = 'Power';
            app.PowerCheckBox.Value = true;
            app.TemperatureCheckBox = uicheckbox(app.GridLayout10);
            app.TemperatureCheckBox.Text = 'Temperature';
            app.TemperatureCheckBox.Value = true;
            app.PressureCheckBox = uicheckbox(app.GridLayout10);
            app.PressureCheckBox.Text = 'Pressure';
            app.PressureCheckBox.Value = true;
            app.LogFileCheckBox = uicheckbox(app.GridLayout10);
            app.LogFileCheckBox.Text = 'Log File';
            app.LogFileCheckBox.Value = true;
            app.BeginMonitoringButton = uibutton(app.GridLayout7, 'push');
            app.BeginMonitoringButton.Text = 'Begin Monitoring';
            app.StatusreadyLabel_2 = uilabel(app.GridLayout7);
            app.StatusreadyLabel_2.Text = 'Status: ready';
            app.Panel_13 = uipanel(app.GridLayout7);
            app.GridLayout9_2 = uigridlayout(app.Panel_13);
            app.EditField = uieditfield(app.GridLayout9_2, 'text');
            app.EditField.Value = 'Input File Name';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TextAreaLabel = uilabel(app.SettingsTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.SettingsTab);
            app.TextArea.Value = {'Pressure calibration'; 'Plot homer or generator'; ''};
            app.PIDTab = uitab(app.TabGroup);
            app.PIDTab.Title = 'P&ID';
            app.GridLayout6 = uigridlayout(app.PIDTab);
            app.Image = uiimage(app.GridLayout6);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'PIDAPP.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeliaControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end