classdef PressureControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout3              matlab.ui.container.GridLayout
        ControlPanel             matlab.ui.container.Panel
        barLabel                 matlab.ui.control.Label
        Gauge                    matlab.ui.control.Gauge
        PressureLabel            matlab.ui.control.Label
        SetPointKnob             matlab.ui.control.Knob
        SetPointKnobLabel        matlab.ui.control.Label
        SensorPanel              matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        DisconnectButton_2       matlab.ui.control.Button
        ConnectButton_2          matlab.ui.control.Button
        COMEditField_2           matlab.ui.control.NumericEditField
        COMEditField_2Label      matlab.ui.control.Label
        EPCPanel                 matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        DisconnectButton         matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        COMEditField             matlab.ui.control.NumericEditField
        COMEditFieldLabel        matlab.ui.control.Label
        PressureControllerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        EPCconnected = 0 % 0 for disconnected, 1 for connected
        sensorconnected = 0 % 0 for disconnected, 1 for connected
        EPCobj = [] % Object placeholder for EPC
        sensorObj = [] % Placeholder for sensor
        PollTimer timer % Used to get pressure data
    end
    
    methods (Access = private)
        
        function pollSerialDevice(app)


            voltage = sensor_read_voltage(app.sensorObj)
            
            % Convert to pressure
            pressure = (voltage - 0.86)/(0.86*5 - 0.86)*40 + 1
            
            % Update label
            app.barLabel.Text = string(round(pressure,0,"decimals")) + " bar"
    
            % Update gauge
            app.Gauge.Value = pressure
            
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PollTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 5, ...
                'TimerFcn', @(~,~)app.pollSerialDevice());
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Connect to EPC button

            % Run connect driver
            % Enable dial
            % Grey out button
            % Enable disconnect button
            
            com = app.COMEditField.Value
            app.EPCobj = EPC_connect(com)
            
            % Enable voltage control if BOTH are connected
            app.EPCconnected = 1
            if app.sensorconnected * app.EPCconnected == 1
                app.SetPointKnob.Enable = "on"
            end

            app.ConnectButton.Enable = "off"
            app.ConnectButton.BackgroundColor = [0.15,0.15,0.15]
            app.ConnectButton.FontColor = [0.00,1.00,0.00]

            app.DisconnectButton.Enable = "on"
            app.DisconnectButton.BackgroundColor = [0.00,1.00,0.00]
            app.DisconnectButton.FontColor = [0.15,0.15,0.15]
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % Disconnect from EPC button

            % Run disconnect driver
            EPC_disconnect(app.EPCobj)
            app.EPCconnected = 0


            % Disable dial
            app.SetPointKnob.Enable = "off"

            % Grey out disconnect button
            app.DisconnectButton.Enable = "off"
            app.DisconnectButton.BackgroundColor = [0.15,0.15,0.15]
            app.DisconnectButton.FontColor = [0.00,1.00,0.00]

            % Enable connect button
            app.ConnectButton.Enable = "on"
            app.ConnectButton.BackgroundColor = [0.00,1.00,0.00]
            app.ConnectButton.FontColor = [0.15,0.15,0.15]
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            % Connect to sensor button

            % Run connect driver
            com = app.COMEditField_2.Value
            app.sensorObj = sensor_connect(com)

            % Enable voltage control if BOTH are connected
            app.sensorconnected = 1
            if app.sensorconnected * app.EPCconnected == 1
                app.SetPointKnob.Enable = "on"
            end

            % Begin timer
            start(app.PollTimer)
                
            % Grey out connect button
            app.ConnectButton_2.Enable = "off"
            app.ConnectButton_2.BackgroundColor = [0.15,0.15,0.15]
            app.ConnectButton_2.FontColor = [0.00,1.00,0.00]

            % Enable disconnct button
            app.DisconnectButton_2.Enable = "on"
            app.DisconnectButton_2.BackgroundColor = [0.00,1.00,0.00]
            app.DisconnectButton_2.FontColor = [0.15,0.15,0.15]
        end

        % Button pushed function: DisconnectButton_2
        function DisconnectButton_2Pushed(app, event)
            % Disconnect from sensor button

            % Run disconnect driver
            sensor_disconnect(app.sensorObj)
            app.sensorconnected = 0

            % Disable dial
            app.SetPointKnob.Enable = "off"

            % Disable timer
            stop(app.PollTimer)

            % Grey out disconnect button
            app.DisconnectButton_2.Enable = "off"
            app.DisconnectButton_2.BackgroundColor = [0.15,0.15,0.15]
            app.DisconnectButton_2.FontColor = [0.00,1.00,0.00]

            % Enable connect button
            app.ConnectButton_2.Enable = "on"
            app.ConnectButton_2.BackgroundColor = [0.00,1.00,0.00]
            app.ConnectButton_2.FontColor = [0.15,0.15,0.15]
        end

        % Value changed function: SetPointKnob
        function SetPointKnobValueChanged(app, event)
            value = app.SetPointKnob.Value / 8.75;
            
            % Set the voltage on the EPC
            EPC_set_voltage(app.EPCobj,0,value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.PressureControllerLabel = uilabel(app.GridLayout3);
            app.PressureControllerLabel.Text = 'Pressure Controller';
            app.EPCPanel = uipanel(app.GridLayout3);
            app.EPCPanel.Title = 'EPC';
            app.GridLayout = uigridlayout(app.EPCPanel);
            app.COMEditFieldLabel = uilabel(app.GridLayout);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.GridLayout, 'numeric');
            app.COMEditField.Value = 19;
            app.ConnectButton = uibutton(app.GridLayout, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.GridLayout, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.SensorPanel = uipanel(app.GridLayout3);
            app.SensorPanel.Title = 'Sensor';
            app.GridLayout2 = uigridlayout(app.SensorPanel);
            app.COMEditField_2Label = uilabel(app.GridLayout2);
            app.COMEditField_2Label.Text = 'COM';
            app.COMEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.COMEditField_2.Value = 18;
            app.ConnectButton_2 = uibutton(app.GridLayout2, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.DisconnectButton_2 = uibutton(app.GridLayout2, 'push');
            app.DisconnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButton_2Pushed, true);
            app.DisconnectButton_2.Text = 'Disconnect';
            app.ControlPanel = uipanel(app.GridLayout3);
            app.ControlPanel.Title = 'Control';
            app.SetPointKnobLabel = uilabel(app.ControlPanel);
            app.SetPointKnobLabel.Enable = 'off';
            app.SetPointKnobLabel.Text = 'Set Point';
            app.SetPointKnob = uiknob(app.ControlPanel, 'continuous');
            app.SetPointKnob.Limits = [0 30];
            app.SetPointKnob.ValueChangedFcn = createCallbackFcn(app, @SetPointKnobValueChanged, true);
            app.SetPointKnob.Enable = 'off';
            app.PressureLabel = uilabel(app.ControlPanel);
            app.PressureLabel.Text = 'Pressure:';
            app.Gauge = uigauge(app.ControlPanel, 'circular');
            app.Gauge.Limits = [0 30];
            app.Gauge.Value = 1;
            app.barLabel = uilabel(app.ControlPanel);
            app.barLabel.Text = '1 bar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PressureControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end